classdef rgb_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        Axes             matlab.ui.control.UIAxes
        GridLayout2      matlab.ui.container.GridLayout
        PanelR           matlab.ui.container.Panel
        K1               matlab.ui.control.Knob
        L1               matlab.ui.control.Lamp
        Sw1              matlab.ui.control.Switch
        PanelG           matlab.ui.container.Panel
        Sw2              matlab.ui.control.Switch
        K2               matlab.ui.control.Knob
        L2               matlab.ui.control.Lamp
        PanelB           matlab.ui.container.Panel
        Sw3              matlab.ui.control.Switch
        K3               matlab.ui.control.Knob
        L3               matlab.ui.control.Lamp
        GridLayout3      matlab.ui.container.GridLayout
        GridLayout4      matlab.ui.container.GridLayout
        LRGB             matlab.ui.control.Lamp
        LEDLabel         matlab.ui.control.Label
        GridLayout5      matlab.ui.container.GridLayout
        BrightnessLabel  matlab.ui.control.Label
        LightGauge       matlab.ui.control.SemicircularGauge
        GridLayout6      matlab.ui.container.GridLayout
        GridLayout7      matlab.ui.container.GridLayout
        Lamp             matlab.ui.control.Lamp
        ConnectionLabel  matlab.ui.control.Label
        Help             matlab.ui.control.Button
        GridLayout8      matlab.ui.container.GridLayout
        Simulate         matlab.ui.control.CheckBox
        GridLayout10     matlab.ui.container.GridLayout
        BCon             matlab.ui.control.Button
        BDiscon          matlab.ui.control.Button
        HardwarePanel    matlab.ui.container.Panel
        GridLayout11     matlab.ui.container.GridLayout
        Refresh          matlab.ui.control.Button
        PortLabel        matlab.ui.control.Label
        PortList         matlab.ui.control.DropDown
        PanelCon         matlab.ui.container.Panel
    end

    
    properties (Access = private)
        TIME_STEP = 0.07 % fixed time step of calling SendData function
        % define colors
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        GRAY_COLOR = [0.4706 0.5059 0.4706]
        s % serial port
        t % timer (for update GUI and send data)
        color_values = zeros(1, 3) % knobs values (K1, K2, K3)
        light_value = 0 % light sensor value
        light_plot % light plot handler
        color_plots % color plots handlers
    end
    
    methods (Access = private)
        
        function updateGUI(app, color_values, light_value)
            % plot color values in GUI
            for i = 1 : length(app.color_plots)
                % (discard the 1-st element, append the new element)
                app.color_plots(i).YData = [app.color_plots(i).YData(2:end) color_values(i)];
            end
            
            % change color of GUI RGB lamp
            app.LRGB.Color = color_values / 100; % scale color values to 0..1 range
            
            % plot light sensor values as an area in GUI
            app.light_plot.YData = [app.light_plot.YData(2:end) light_value];
            
            % update LightGauge with the latest light value
            app.LightGauge.Value = light_value;
        end
        
        function sendData(app, color_values)
            % if output buffer is empty write values to serial port in asyncronous mode
            if ~app.Simulate.Value
                if app.s.BytesToOutput == 0
                    color_values = 0.5 * color_values; % decrease maximum brightness to prevent your eyes burning
                    values_to_send = uint8(2.55 * (100 - color_values)); % scale color values to 255..0 uint8 range
                    fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
                end
            else
                app.light_value = sum(color_values) / 3;
            end
        end
        
        
        function readData(app, ~, ~)
            % read and scale data from serial port
            app.light_value = double(fread(app.s, 1, 'uint16')) / 10;
        end
        
        
        function timerFunction(app, ~, ~)
            % read switches values from GUI into 1x3 array
            color_switches = [app.Sw1.Value, app.Sw2.Value, app.Sw3.Value];
            % calculate color values taking into account switches postions
            color_values_sw = app.color_values .* color_switches; % knobs values (K1, K2, K3) with considering switches
            app.updateGUI(color_values_sw, app.light_value);
            app.sendData(color_values_sw);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up swithes values
            app.Sw1.ItemsData = [0 1];
            app.Sw2.ItemsData = [0 1];
            app.Sw3.ItemsData = [0 1];
            % bound swithes with their lamps
            app.Sw1.UserData = app.L1;
            app.Sw2.UserData = app.L2;
            app.Sw3.UserData = app.L3;
            % mark knobs with index numbers
            app.K1.UserData = 1;
            app.K2.UserData = 2;
            app.K3.UserData = 3;
            % define lamps colors
            app.L1.Color = app.GREEN_COLOR;
            app.L2.Color = app.GREEN_COLOR;
            app.L3.Color = app.GREEN_COLOR;
            
            % read knob values into 1x3 array
            app.color_values = [app.K1.Value, app.K2.Value, app.K3.Value];
            % define time vector for plotting
            time = (0 : app.TIME_STEP : 10)';
            
            % init and set up axes
            app.Axes.XLim = [0 10];
            app.Axes.YLim = [0 100];
            app.Axes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Axes);
            
            % define color order fot plotting
            app.Axes.ColorOrder = ...
                [0.850,  0.325,  0.098;
                0.466,  0.674,  0.188;
                0.000,  0.447,  0.741;
                0.839,  0.839,  0.024];
            
            % plot light value in GUI
            app.light_plot = area(app.Axes, time, zeros(length(time), 1),...
                'FaceColor', 'yellow', 'FaceAlpha', 0.5, 'EdgeColor', 'yellow');
            hold(app.Axes, 'on'); % freeze current axes
            % plot color values in GUI
            app.color_plots = plot(app.Axes, time, zeros(length(time), 3), 'LineWidth', 2);
            hold(app.Axes, 'off'); % unfreeze axes
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.timerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                if ~app.Simulate.Value
                    stopasync(app.s)
                    fclose(app.s);
                end
                app.Lamp.Color = app.RED_COLOR;
                app.Simulate.Enable = 'on';
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"
                if ~app.Simulate.Value
                    app.s = serial(app.PortList.Value);
                    % call ReadData function if buffer contains uint16 (2 bytes)
                    app.s.BytesAvailableFcnCount = 2;
                    app.s.BytesAvailableFcnMode = 'byte';
                    app.s.BytesAvailableFcn = @app.readData;
                    fopen(app.s);
                end
                start(app.t);
                app.Lamp.Color = app.GREEN_COLOR;
                app.Simulate.Enable = 'off';
            end
        end

        % Value changing function: K1, K2, K3
        function KValueChanging(app, event)
            % read knob value if it is changing
            knob = event.Source; % define knob
            i = knob.UserData; % extract knob index (1:red, 2:green, 3:blue)
            app.color_values(i) = event.Value; % change appropriate element of values vector
        end

        % Value changed function: Sw1, Sw2, Sw3
        function SwValueChanged(app, event)
            % change lamp color if switch value has changed
            sw = event.Source; % define switch
            lamp = sw.UserData; % extract a pointer to corresponding lamp
            if sw.Value
                lamp.Color = app.GREEN_COLOR;
            else
                lamp.Color = app.GRAY_COLOR;
            end
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Value changed function: Simulate
        function SimulateValueChanged(app, event)
            app.BDisconButtonPushed();
            value = app.Simulate.Value;
            app.HardwarePanel.Visible = ~value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app);
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            open rgb_doc.mlx
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Instrument Control over Serial';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Axes = uiaxes(app.GridLayout);
            title(app.Axes, 'Values')
            xlabel(app.Axes, 'Time, sec')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.PanelR = uipanel(app.GridLayout2);
            app.PanelR.Title = 'RED LED';
            app.K1 = uiknob(app.PanelR, 'continuous');
            app.K1.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K1.Value = 100;
            app.L1 = uilamp(app.PanelR);
            app.Sw1 = uiswitch(app.PanelR, 'slider');
            app.Sw1.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw1.Value = 'On';
            app.PanelG = uipanel(app.GridLayout2);
            app.PanelG.Title = 'GREEN LED';
            app.Sw2 = uiswitch(app.PanelG, 'slider');
            app.Sw2.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw2.Value = 'On';
            app.K2 = uiknob(app.PanelG, 'continuous');
            app.K2.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K2.Value = 100;
            app.L2 = uilamp(app.PanelG);
            app.PanelB = uipanel(app.GridLayout2);
            app.PanelB.Title = 'BLUE LED';
            app.Sw3 = uiswitch(app.PanelB, 'slider');
            app.Sw3.ValueChangedFcn = createCallbackFcn(app, @SwValueChanged, true);
            app.Sw3.Value = 'On';
            app.K3 = uiknob(app.PanelB, 'continuous');
            app.K3.ValueChangingFcn = createCallbackFcn(app, @KValueChanging, true);
            app.K3.Value = 100;
            app.L3 = uilamp(app.PanelB);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.LRGB = uilamp(app.GridLayout4);
            app.LEDLabel = uilabel(app.GridLayout4);
            app.LEDLabel.Text = 'LED';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.BrightnessLabel = uilabel(app.GridLayout5);
            app.BrightnessLabel.Text = 'Brightness';
            app.LightGauge = uigauge(app.GridLayout5, 'semicircular');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Lamp = uilamp(app.GridLayout7);
            app.ConnectionLabel = uilabel(app.GridLayout7);
            app.ConnectionLabel.Text = 'Connection';
            app.Help = uibutton(app.GridLayout7, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.Simulate = uicheckbox(app.GridLayout8);
            app.Simulate.ValueChangedFcn = createCallbackFcn(app, @SimulateValueChanged, true);
            app.Simulate.Text = 'Simulate hardware';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.BCon = uibutton(app.GridLayout10, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.GridLayout10, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.HardwarePanel = uipanel(app.GridLayout8);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.PanelCon = uipanel(app.GridLayout);
            app.PanelCon.Title = '      Connection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rgb_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end